classdef SCAPE_datajoint < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        ListBox                        matlab.ui.control.ListBox
        RecorderDropDownLabel          matlab.ui.control.Label
        RecorderDropDown               matlab.ui.control.DropDown
        ProjectDropDownLabel           matlab.ui.control.Label
        ProjectDropDown                matlab.ui.control.DropDown
        Panel                          matlab.ui.container.Panel
        NoteEditFieldLabel             matlab.ui.control.Label
        NoteEditField                  matlab.ui.control.EditField
        RunConditionDropDownLabel      matlab.ui.control.Label
        RunConditionDropDown           matlab.ui.control.DropDown
        SpecimenEditFieldLabel         matlab.ui.control.Label
        SpecimenEditField              matlab.ui.control.EditField
        DualColourCheckBox             matlab.ui.control.CheckBox
        ScanSizeGBEditFieldLabel       matlab.ui.control.Label
        ScanSizeGBEditField            matlab.ui.control.NumericEditField
        ScanStatusDropDownLabel        matlab.ui.control.Label
        ScanStatusDropDown             matlab.ui.control.DropDown
        ScannerTypeDropDownLabel       matlab.ui.control.Label
        ScannerTypeDropDown            matlab.ui.control.DropDown
        OrganDropDownLabel             matlab.ui.control.Label
        OrganDropDown                  matlab.ui.control.DropDown
        BehavioralSetupDropDownLabel   matlab.ui.control.Label
        BehavioralSetupDropDown        matlab.ui.control.DropDown
        StimulusSetupDropDownLabel     matlab.ui.control.Label
        StimulusSetupDropDown          matlab.ui.control.DropDown
        FilterEditFieldLabel           matlab.ui.control.Label
        FilterEditField                matlab.ui.control.EditField
        Panel_2                        matlab.ui.container.Panel
        UnitDropDownLabel              matlab.ui.control.Label
        UnitDropDown                   matlab.ui.control.DropDown
        AgeEditFieldLabel              matlab.ui.control.Label
        AgeEditField                   matlab.ui.control.NumericEditField
        DevelopmentStageDropDownLabel  matlab.ui.control.Label
        DevelopmentStageDropDown       matlab.ui.control.DropDown
        TabGroup                       matlab.ui.container.TabGroup
        Camera1Tab                     matlab.ui.container.Tab
        CameraDropDownLabel            matlab.ui.control.Label
        CameraDropDown                 matlab.ui.control.DropDown
        TubeLensDropDownLabel          matlab.ui.control.Label
        TubeLensDropDown               matlab.ui.control.DropDown
        FocalLengthZoomEditFieldLabel  matlab.ui.control.Label
        FocalLengthZoomEditField       matlab.ui.control.NumericEditField
        Camera2Tab                     matlab.ui.container.Tab
        CameraDropDown_2Label          matlab.ui.control.Label
        CameraDropDown_2               matlab.ui.control.DropDown
        TubeLensDropDown_2Label        matlab.ui.control.Label
        TubeLensDropDown_2             matlab.ui.control.DropDown
        FocalLengthZoomEditField_2Label  matlab.ui.control.Label
        FocalLengthZoomEditField_2     matlab.ui.control.NumericEditField
        DeployCheckBox                 matlab.ui.control.CheckBox
        LaserTable                     matlab.ui.control.Table
        AiTable                        matlab.ui.control.Table
        UploadButton                   matlab.ui.control.Button
        SpeciesDropDownLabel           matlab.ui.control.Label
        SpeciesDropDown                matlab.ui.control.DropDown
        SCAPEConfigDropDownLabel       matlab.ui.control.Label
        SCAPEConfigDropDown            matlab.ui.control.DropDown
    end

    
    properties (Access = private)
        lab_schema;
        microscope_schema;
        organism_schema;
        setup_schema;
        experiment_schema;
        specimen_list;
        session_data;
        scan_info;
    end
    
    methods (Access = private)
        
        function  refresh_scan(app)
            %% Execute at the beginning of each scan
            runName = app.ListBox.Value;
            newstr = split(runName,'_');
            app.SpecimenEditField.Value = strcat(newstr{1},'_',app.session_data.session_date);
            
            if strcmp(newstr{end},'HR')
                app.ScannerTypeDropDown.Value = 'HR';
            end
            
            %% Load Info file
            load(strcat(runName,'_info.mat'),"-mat",'info');
            app.scan_info = info;
            
            if strcmp(app.scan_info.scanStatus,'scan complete!')
                app.ScanStatusDropDown.Value='Successful';
            else
                app.ScanStatusDropDown.Value='Interrupted';
            end
            app.NoteEditField.Value = info.experiment_notes;
            
            %% Laser
            laser_power = [info.laserpower405 info.laserpower488 info.laserpower561 info.laserpower594 info.laserpower637];
            app.LaserTable.Data = num2cell([laser_power ;laser_power ]);
            
            %% Ai channels NOTICE: NOT IMPLEMENTED YET!!
            
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %% Prompt for session folder
            [codepath,~,~] = fileparts(mfilename('fullpath'));
            addpath(codepath);
            H = SCAPE_datajoint_session;
            waitfor(H);
            app.session_data = evalin('base', 'session_data');
            
            filesInDataDirectory = dir('*info.mat');
            [~,idx] = sort({filesInDataDirectory.date});
            filesInDataDirectory = filesInDataDirectory(idx);
            runNames = cell(1,length(filesInDataDirectory));
            for i = 1:length(filesInDataDirectory)
                tmpname = filesInDataDirectory(i).name;
                runNames(i) = cellstr(tmpname(1:end-9));
            end
            app.ListBox.Items = runNames; 
            app.ListBox.Value = runNames(1);
            
            %% Connect with Datajoint
            app.lab_schema = lab.getSchema;
            app.microscope_schema = microscope.getSchema;
            app.organism_schema = organism.getSchema;
            app.setup_schema = setup.getSchema;
            app.experiment_schema = experiment.getSchema;
            
            %% Session initialization
            app.RecorderDropDown.Items = {app.session_data.user};
            if isfield(app.session_data,'species')
                app.SpeciesDropDown.Items = {app.session_data.species};
            end
            if isfield(app.session_data,'project')
                app.ProjectDropDown.Items = {app.session_data.project};
            end
            app.SCAPEConfigDropDown.Items = {app.session_data.scape_config_number};
            
            if isfield(app.session_data,'species')
                app.OrganDropDown.Items = ['NULL' (fetchn(app.organism_schema.v.SpeciesOrgan & strcat('species = "',app.session_data.species,'"'),'organ'))'];
            end
            app.BehavioralSetupDropDown.Items = ['NULL' (fetchn(app.setup_schema.v.BehavioralSetup,'behavior_setup'))'];
            app.StimulusSetupDropDown.Items = ['NULL' (fetchn(app.setup_schema.v.StimSetup,'stim_setup'))'];
            
            %% Camera
            app.CameraDropDown.Items = (fetchn(app.microscope_schema.v.Camera,'camera'))';
            app.CameraDropDown_2.Items = (fetchn(app.microscope_schema.v.Camera,'camera'))';
            app.TubeLensDropDown.Items = (fetchn(app.microscope_schema.v.TubeLens,'tubelens'))';
            app.TubeLensDropDown_2.Items = (fetchn(app.microscope_schema.v.TubeLens,'tubelens'))';
            
            %% Laser
            laser_struct = fetch(app.setup_schema.v.ScapeconfigLaser & strcat('scape_config_number = "',app.session_data.scape_config_number,'"'),'*');
            laser_struct = sortrows(struct2table(laser_struct),2);
            app.LaserTable.ColumnName = laser_struct.laser;
            app.LaserTable.Data = cell(2,length(app.LaserTable.ColumnName));
            
            refresh_scan(app);
        end

        % Button pushed function: UploadButton
        function UploadButtonPushed(app, event)
            %% Store new specimen name
               
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ListBox = uilistbox(app.UIFigure);
            app.ListBox.Enable = 'off';
            app.RecorderDropDownLabel = uilabel(app.UIFigure);
            app.RecorderDropDownLabel.Enable = 'off';
            app.RecorderDropDownLabel.Text = 'Recorder';
            app.RecorderDropDown = uidropdown(app.UIFigure);
            app.RecorderDropDown.Enable = 'off';
            app.ProjectDropDownLabel = uilabel(app.UIFigure);
            app.ProjectDropDownLabel.Enable = 'off';
            app.ProjectDropDownLabel.Text = 'Project';
            app.ProjectDropDown = uidropdown(app.UIFigure);
            app.ProjectDropDown.Items = {};
            app.ProjectDropDown.Enable = 'off';
            app.ProjectDropDown.Value = {};
            app.Panel = uipanel(app.UIFigure);
            app.NoteEditFieldLabel = uilabel(app.Panel);
            app.NoteEditFieldLabel.Text = 'Note';
            app.NoteEditField = uieditfield(app.Panel, 'text');
            app.RunConditionDropDownLabel = uilabel(app.Panel);
            app.RunConditionDropDownLabel.Text = 'Run Condition';
            app.RunConditionDropDown = uidropdown(app.Panel);
            app.RunConditionDropDown.Items = {'Unknown', 'Successful', 'Test', 'Failed Run'};
            app.RunConditionDropDown.Value = 'Unknown';
            app.SpecimenEditFieldLabel = uilabel(app.Panel);
            app.SpecimenEditFieldLabel.Text = 'Specimen';
            app.SpecimenEditField = uieditfield(app.Panel, 'text');
            app.DualColourCheckBox = uicheckbox(app.Panel);
            app.DualColourCheckBox.Text = 'Dual Colour';
            app.ScanSizeGBEditFieldLabel = uilabel(app.Panel);
            app.ScanSizeGBEditFieldLabel.Text = 'Scan Size (GB)';
            app.ScanSizeGBEditField = uieditfield(app.Panel, 'numeric');
            app.ScanStatusDropDownLabel = uilabel(app.Panel);
            app.ScanStatusDropDownLabel.Text = 'Scan Status';
            app.ScanStatusDropDown = uidropdown(app.Panel);
            app.ScanStatusDropDown.Items = {'Successful', 'Interrupted', 'Null'};
            app.ScanStatusDropDown.Value = 'Successful';
            app.ScannerTypeDropDownLabel = uilabel(app.Panel);
            app.ScannerTypeDropDownLabel.Text = 'Scanner Type';
            app.ScannerTypeDropDown = uidropdown(app.Panel);
            app.ScannerTypeDropDown.Items = {'', 'HR', 'Single Frame', 'Stage Scan'};
            app.ScannerTypeDropDown.Value = '';
            app.OrganDropDownLabel = uilabel(app.Panel);
            app.OrganDropDownLabel.Text = 'Organ';
            app.OrganDropDown = uidropdown(app.Panel);
            app.OrganDropDown.Items = {};
            app.OrganDropDown.Value = {};
            app.BehavioralSetupDropDownLabel = uilabel(app.Panel);
            app.BehavioralSetupDropDownLabel.Text = 'Behavioral Setup';
            app.BehavioralSetupDropDown = uidropdown(app.Panel);
            app.BehavioralSetupDropDown.Items = {};
            app.BehavioralSetupDropDown.Value = {};
            app.StimulusSetupDropDownLabel = uilabel(app.Panel);
            app.StimulusSetupDropDownLabel.Text = 'Stimulus Setup';
            app.StimulusSetupDropDown = uidropdown(app.Panel);
            app.StimulusSetupDropDown.Items = {};
            app.StimulusSetupDropDown.Value = {};
            app.FilterEditFieldLabel = uilabel(app.Panel);
            app.FilterEditFieldLabel.Text = 'Filter';
            app.FilterEditField = uieditfield(app.Panel, 'text');
            app.Panel_2 = uipanel(app.Panel);
            app.UnitDropDownLabel = uilabel(app.Panel_2);
            app.UnitDropDownLabel.Text = 'Unit';
            app.UnitDropDown = uidropdown(app.Panel_2);
            app.UnitDropDown.Items = {'Unknown', 'Hours', 'Days', 'Months', 'Years', 'Instar'};
            app.UnitDropDown.Value = 'Unknown';
            app.AgeEditFieldLabel = uilabel(app.Panel_2);
            app.AgeEditFieldLabel.Text = 'Age';
            app.AgeEditField = uieditfield(app.Panel_2, 'numeric');
            app.DevelopmentStageDropDownLabel = uilabel(app.Panel_2);
            app.DevelopmentStageDropDownLabel.Text = 'Development Stage';
            app.DevelopmentStageDropDown = uidropdown(app.Panel_2);
            app.DevelopmentStageDropDown.Items = {'Larva', 'Adult', 'Embryo', 'Others'};
            app.DevelopmentStageDropDown.Value = 'Larva';
            app.TabGroup = uitabgroup(app.Panel);
            app.Camera1Tab = uitab(app.TabGroup);
            app.Camera1Tab.Title = 'Camera 1';
            app.CameraDropDownLabel = uilabel(app.Camera1Tab);
            app.CameraDropDownLabel.Text = 'Camera';
            app.CameraDropDown = uidropdown(app.Camera1Tab);
            app.TubeLensDropDownLabel = uilabel(app.Camera1Tab);
            app.TubeLensDropDownLabel.Text = 'Tube Lens';
            app.TubeLensDropDown = uidropdown(app.Camera1Tab);
            app.FocalLengthZoomEditFieldLabel = uilabel(app.Camera1Tab);
            app.FocalLengthZoomEditFieldLabel.Text = 'Focal Length (Zoom)';
            app.FocalLengthZoomEditField = uieditfield(app.Camera1Tab, 'numeric');
            app.Camera2Tab = uitab(app.TabGroup);
            app.Camera2Tab.Title = 'Camera 2';
            app.CameraDropDown_2Label = uilabel(app.Camera2Tab);
            app.CameraDropDown_2Label.Text = 'Camera';
            app.CameraDropDown_2 = uidropdown(app.Camera2Tab);
            app.TubeLensDropDown_2Label = uilabel(app.Camera2Tab);
            app.TubeLensDropDown_2Label.Text = 'Tube Lens';
            app.TubeLensDropDown_2 = uidropdown(app.Camera2Tab);
            app.FocalLengthZoomEditField_2Label = uilabel(app.Camera2Tab);
            app.FocalLengthZoomEditField_2Label.Text = 'Focal Length (Zoom)';
            app.FocalLengthZoomEditField_2 = uieditfield(app.Camera2Tab, 'numeric');
            app.DeployCheckBox = uicheckbox(app.Camera2Tab);
            app.DeployCheckBox.Text = 'Deploy';
            app.LaserTable = uitable(app.Panel);
            app.LaserTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.LaserTable.RowName = {'Laser Power'; 'Specimen Power'};
            app.AiTable = uitable(app.Panel);
            app.AiTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.AiTable.RowName = {'Channel'; 'Purpose'; 'Description'};
            app.UploadButton = uibutton(app.UIFigure, 'push');
            app.UploadButton.ButtonPushedFcn = createCallbackFcn(app, @UploadButtonPushed, true);
            app.UploadButton.Text = 'Upload';
            app.SpeciesDropDownLabel = uilabel(app.UIFigure);
            app.SpeciesDropDownLabel.Enable = 'off';
            app.SpeciesDropDownLabel.Text = 'Species';
            app.SpeciesDropDown = uidropdown(app.UIFigure);
            app.SpeciesDropDown.Items = {};
            app.SpeciesDropDown.Enable = 'off';
            app.SpeciesDropDown.Value = {};
            app.SCAPEConfigDropDownLabel = uilabel(app.UIFigure);
            app.SCAPEConfigDropDownLabel.Enable = 'off';
            app.SCAPEConfigDropDownLabel.Text = 'SCAPE Config';
            app.SCAPEConfigDropDown = uidropdown(app.UIFigure);
            app.SCAPEConfigDropDown.Enable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SCAPE_datajoint

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end