classdef SCAPE_datajoint_session < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        RecorderDropDownLabel         matlab.ui.control.Label
        RecorderDropDown              matlab.ui.control.DropDown
        SCAPEConfigDropDownLabel      matlab.ui.control.Label
        SCAPEConfigDropDown           matlab.ui.control.DropDown
        ProjectDropDownLabel          matlab.ui.control.Label
        ProjectDropDown               matlab.ui.control.DropDown
        ServerLocationEditFieldLabel  matlab.ui.control.Label
        ServerLocationEditField       matlab.ui.control.EditField
        BackupLocationEditFieldLabel  matlab.ui.control.Label
        BackupLocationEditField       matlab.ui.control.EditField
        SpeciesDropDownLabel          matlab.ui.control.Label
        SpeciesDropDown               matlab.ui.control.DropDown
        SessionInfoLabel              matlab.ui.control.Label
        ConfirmButton                 matlab.ui.control.Button
        DescriptionEditFieldLabel     matlab.ui.control.Label
        DescriptionEditField          matlab.ui.control.EditField
    end

    
    properties (Access = private)
        session_data;
    end
    
    properties (Constant)
        DefaultUser = 'Richard_Yan';
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %% Connect with Datajoint
            lab_schema = lab.getSchema;
            organism_schema = organism.getSchema;
            setup_schema = setup.getSchema;
            
            %% Apps initialization
            app.RecorderDropDown.Items = (fetchn(lab_schema.v.LabMember,'user'))';
            app.RecorderDropDown.Value = app.DefaultUser;
            app.SpeciesDropDown.Items = (fetchn(organism_schema.v.Species,'species'))';
            app.ProjectDropDown.Items = ['NULL' (fetchn(lab_schema.v.Project,'project'))'];
            app.SCAPEConfigDropDown.Items = (fetchn(setup_schema.v.Scapeconfig,'scape_config_number'))';
            
            selpath = uigetdir(matlabroot,'Select Session Folder');
            cd(selpath);
            folder_path=regexp(selpath,filesep,'split');
            app.session_data.session_name = string(folder_path(end));
            d = System.IO.File.GetCreationTime(selpath);
            app.session_data.session_date = datestr(datetime(d.Year, d.Month, d.Day, d.Hour, d.Minute, d.Second),'yyyy-mm-dd');
        end

        % Button pushed function: ConfirmButton
        function ConfirmButtonPushed(app, event)
            experiment_schema = experiment.getSchema;
            
            app.session_data.user = app.RecorderDropDown.Value;
            app.session_data.scape_config_number = app.SCAPEConfigDropDown.Value;
            if ~strcmp(app.ProjectDropDown.Value,'NULL')
                app.session_data.project = app.ProjectDropDown.Value;
            end
            app.session_data.species = app.SpeciesDropDown.Value;
            app.session_data.data_directory = app.ServerLocationEditField.Value;
            app.session_data.backup_location = app.BackupLocationEditField.Value;
            app.session_data.session_description = app.DescriptionEditField.Value;
            insert(experiment_schema.v.Session,app.session_data);
            assignin('base','session_data',app.session_data)
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.RecorderDropDownLabel = uilabel(app.UIFigure);
            app.RecorderDropDownLabel.Text = 'Recorder';
            app.RecorderDropDown = uidropdown(app.UIFigure);
            app.SCAPEConfigDropDownLabel = uilabel(app.UIFigure);
            app.SCAPEConfigDropDownLabel.Text = 'SCAPE Config';
            app.SCAPEConfigDropDown = uidropdown(app.UIFigure);
            app.ProjectDropDownLabel = uilabel(app.UIFigure);
            app.ProjectDropDownLabel.Text = 'Project';
            app.ProjectDropDown = uidropdown(app.UIFigure);
            app.ProjectDropDown.Items = {};
            app.ProjectDropDown.Value = {};
            app.ServerLocationEditFieldLabel = uilabel(app.UIFigure);
            app.ServerLocationEditFieldLabel.Text = 'Server Location';
            app.ServerLocationEditField = uieditfield(app.UIFigure, 'text');
            app.BackupLocationEditFieldLabel = uilabel(app.UIFigure);
            app.BackupLocationEditFieldLabel.Text = 'Backup Location';
            app.BackupLocationEditField = uieditfield(app.UIFigure, 'text');
            app.SpeciesDropDownLabel = uilabel(app.UIFigure);
            app.SpeciesDropDownLabel.Text = 'Species';
            app.SpeciesDropDown = uidropdown(app.UIFigure);
            app.SessionInfoLabel = uilabel(app.UIFigure);
            app.SessionInfoLabel.Text = 'Session Info.';
            app.ConfirmButton = uibutton(app.UIFigure, 'push');
            app.ConfirmButton.ButtonPushedFcn = createCallbackFcn(app, @ConfirmButtonPushed, true);
            app.ConfirmButton.Text = 'Confirm';
            app.DescriptionEditFieldLabel = uilabel(app.UIFigure);
            app.DescriptionEditFieldLabel.Text = 'Description';
            app.DescriptionEditField = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SCAPE_datajoint_session

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end