classdef SCAPE_datajoint_specimen < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        SourceDropDownLabel            matlab.ui.control.Label
        SourceDropDown                 matlab.ui.control.DropDown
        GenotypeDropDownLabel          matlab.ui.control.Label
        GenotypeDropDown               matlab.ui.control.DropDown
        TissueTypeDropDownLabel        matlab.ui.control.Label
        TissueTypeDropDown             matlab.ui.control.DropDown
        PreparationTypeDropDownLabel   matlab.ui.control.Label
        PreparationTypeDropDown        matlab.ui.control.DropDown
        DescriptionEditFieldLabel      matlab.ui.control.Label
        DescriptionEditField           matlab.ui.control.EditField
        OKButton                       matlab.ui.control.Button
        PreparationTImeEditFieldLabel  matlab.ui.control.Label
        PreparationTImeEditField       matlab.ui.control.EditField
        YYYYMMDDHHMMSSLabel            matlab.ui.control.Label
    end

    
    properties (Access = private)
        lab_schema;
        organism_schema;
        experiment_schema
        species;
        specimen;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, species, specimen)
            %% Connect with Datajoint
            app.lab_schema = lab.getSchema;
            app.organism_schema = organism.getSchema;
            app.experiment_schema = experiment.getSchema;
            
            %% Set app items
            app.species = species;
            app.specimen = specimen;
            app.SourceDropDown.Items = (fetchn(app.lab_schema.v.LabMember,'user'))';
            if ~strcmp(species,'NULL')
                app.GenotypeDropDown.Items = ['NULL' (fetchn(app.organism_schema.v.SpeciesGenotype & strcat('species = "',species,'"'),'genotype_nickname'))'];
            else
                app.GenotypeDropDown.Items = {'NULL'};
            end
            app.PreparationTypeDropDown.Items = ['NULL' (fetchn(app.organism_schema.v.PreparationType,'prep_type'))'];
            app.TissueTypeDropDown.Items = ['NULL' (fetchn(app.organism_schema.v.TissueType,'tissue_type'))'];
            
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)

            specimen_data = struct('specimen',app.specimen,'source',app.SourceDropDown.Value,'species',app.species,...
                'specimen_description',app.DescriptionEditField.Value);
            if ~strcmp(app.GenotypeDropDown.Value, 'NULL')
                specimen_data.genotype_nickname=app.GenotypeDropDown.Value;
            end
            if ~strcmp(app.TissueTypeDropDown.Value, 'NULL')
                specimen_data.tissue_type=app.TissueTypeDropDown.Value;
            end
            if ~strcmp(app.PreparationTypeDropDown.Value, 'NULL')
                specimen_data.prep_type=app.PreparationTypeDropDown.Value;
            end
            if ~isempty(app.PreparationTImeEditField.Value)
                specimen_data.prep_time=app.PreparationTImeEditField.Value;
            end
            insert(app.experiment_schema.v.Specimen, specimen_data);
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SourceDropDownLabel = uilabel(app.UIFigure);
            app.SourceDropDownLabel.Text = 'Source';
            app.SourceDropDown = uidropdown(app.UIFigure);
            app.GenotypeDropDownLabel = uilabel(app.UIFigure);
            app.GenotypeDropDownLabel.Text = 'Genotype';
            app.GenotypeDropDown = uidropdown(app.UIFigure);
            app.TissueTypeDropDownLabel = uilabel(app.UIFigure);
            app.TissueTypeDropDownLabel.Text = 'Tissue Type';
            app.TissueTypeDropDown = uidropdown(app.UIFigure);
            app.PreparationTypeDropDownLabel = uilabel(app.UIFigure);
            app.PreparationTypeDropDownLabel.Text = 'Preparation Type';
            app.PreparationTypeDropDown = uidropdown(app.UIFigure);
            app.DescriptionEditFieldLabel = uilabel(app.UIFigure);
            app.DescriptionEditFieldLabel.Text = 'Description';
            app.DescriptionEditField = uieditfield(app.UIFigure, 'text');
            app.OKButton = uibutton(app.UIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.PreparationTImeEditFieldLabel = uilabel(app.UIFigure);
            app.PreparationTImeEditFieldLabel.Text = 'Preparation TIme';
            app.PreparationTImeEditField = uieditfield(app.UIFigure, 'text');
            app.YYYYMMDDHHMMSSLabel = uilabel(app.UIFigure);
            app.YYYYMMDDHHMMSSLabel.Text = '(YYYY-MM-DD HH:MM:SS)';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SCAPE_datajoint_specimen(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end